classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        StartJournyButton         matlab.ui.control.Button
        Image3                    matlab.ui.control.Image
        ProfilePictureLabel       matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        NationalityDropDown       matlab.ui.control.DropDown
        NationalityDropDownLabel  matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        edited_photo;
        face_path;
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Visible="off";
            app.Image2.Visible="off";                    
            app.StartJournyButton.Visible="off";         
            app.Image3.Visible="off";
            app.Button.Visible="off";
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.png'}, 'Select file');
            app.face_path=fullfile(path,file);
            %a= imread(app.face_path);
            %imshow(a);
            [app.edited_photo,iss_photo] = (pyrunfile("reduced.py",["img1","iss"],face_file=app.face_path));
            app.edited_photo=uint8(app.edited_photo);
            iss_photo=uint8(iss_photo);
            imwrite(iss_photo,"photo_iss.jpg");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
        
            %app2             
            app.ProfilePictureLabel.Visible="off";       
            app.BrowseButton.Visible="off";               
            app.NextButton.Visible="off";                 
            app.NationalityDropDown.Visible="off";        
            app.NationalityDropDownLabel.Visible="off";   
            app.NameEditField.Visible="off";              
            app.NameEditFieldLabel.Visible="off";         
            app.Image.Visible="off"; 
            app.Label.Visible="on";
            app.Image2.Visible="on";                    
            app.StartJournyButton.Visible="on";         
            app.Image3.Visible="on";  
            message="Congratulations "+app.NameEditField.Value+ ", you will represent your country "+app.NationalityDropDown.Value;
            message=message+" in the next space mission. We belive you deserve it as you are a SUPERHERO.";
            app.Label.Text=message;
            app.Label.FontName="Bookman";
            app.Label.FontSize=20;
            app.Label.FontWeight="Bold";
            app.Label.HorizontalAlignment="center";
            app.Label.FontColor=[0 0 1];
            app.Image3.ImageSource=app.edited_photo;
        end

        % Button pushed function: StartJournyButton
        function StartJournyButtonPushed(app, event)
            app.Button.Visible="on";
            app.Button.Icon = '300.gif';
            pause(1.35);
            app2;
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot 2022-09-27 193542.jpg';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NationalityDropDownLabel = uilabel(app.UIFigure);
            app.NationalityDropDownLabel.Text = 'Nationality';
            app.NationalityDropDown = uidropdown(app.UIFigure);
            app.NationalityDropDown.Items = {'Egypt', 'United States', 'England', 'France'};
            app.NationalityDropDown.Value = 'Egypt';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProfilePictureLabel = uilabel(app.UIFigure);
            app.ProfilePictureLabel.Text = 'Profile Picture';
            app.Image3 = uiimage(app.UIFigure);
            app.StartJournyButton = uibutton(app.UIFigure, 'push');
            app.StartJournyButton.ButtonPushedFcn = createCallbackFcn(app, @StartJournyButtonPushed, true);
            app.StartJournyButton.Text = 'Start Journy';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'BlackVersion.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end