classdef app_hungry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image11              matlab.ui.control.Image
        GotitButton          matlab.ui.control.Button
        Image10              matlab.ui.control.Image
        TextArea_2           matlab.ui.control.TextArea
        BonappetitLabel      matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
        CheesecakeCheckBox   matlab.ui.control.CheckBox
        FruitsCheckBox       matlab.ui.control.CheckBox
        IcecreamCheckBox     matlab.ui.control.CheckBox
        DountsCheckBox       matlab.ui.control.CheckBox
        SpaghettiCheckBox    matlab.ui.control.CheckBox
        VegetablesCheckBox   matlab.ui.control.CheckBox
        SeafoodCheckBox      matlab.ui.control.CheckBox
        BurgerfriesCheckBox  matlab.ui.control.CheckBox
        DessertLabel         matlab.ui.control.Label
        MainMealLabel        matlab.ui.control.Label
        Image9               matlab.ui.control.Image
        Image8               matlab.ui.control.Image
        OrderButton          matlab.ui.control.Button
        Image7               matlab.ui.control.Image
        TextArea             matlab.ui.control.TextArea
        Image6               matlab.ui.control.Image
        Image5               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 
            app.BonappetitLabel.Visible="off";      
            app.Image_2.Visible="off";
            app.Image11.Visible="off";
            app.Image10.Visible="off";  
            app.TextArea_2.Visible="off";
            app.GotitButton.Visible="off"; 
        end

        % Value changed function: BurgerfriesCheckBox
        function BurgerfriesCheckBoxValueChanged(app, event)
            value = app.BurgerfriesCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Burger & fries."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Burger & fries.");
            end
        end

        % Value changed function: SeafoodCheckBox
        function SeafoodCheckBoxValueChanged(app, event)
            value = app.SeafoodCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Seafood."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Seafood.");
            end
        end

        % Value changed function: VegetablesCheckBox
        function VegetablesCheckBoxValueChanged(app, event)
            value = app.VegetablesCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Vegetables."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Vegetables.");
            end
        end

        % Value changed function: SpaghettiCheckBox
        function SpaghettiCheckBoxValueChanged(app, event)
            value = app.SpaghettiCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Spaggetti."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Spaggetti.");
            end
        end

        % Value changed function: DountsCheckBox
        function DountsCheckBoxValueChanged(app, event)
            value = app.DountsCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Dounts."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Dounts.");
            end
        end

        % Value changed function: IcecreamCheckBox
        function IcecreamCheckBoxValueChanged(app, event)
            value = app.IcecreamCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Icecream."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Icecream.");
            end
        end

        % Value changed function: FruitsCheckBox
        function FruitsCheckBoxValueChanged(app, event)
            value = app.FruitsCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Fruits."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Fruits.");
            end
        end

        % Value changed function: CheesecakeCheckBox
        function CheesecakeCheckBoxValueChanged(app, event)
            value = app.CheesecakeCheckBox.Value;
            if value==1
                app.TextArea.Value=[app.TextArea.Value;"Cheesecake."];
            else
                app.TextArea.Value=app.TextArea.Value(app.TextArea.Value~="Cheesecake.");
            end
        end

        % Button pushed function: OrderButton
        function OrderButtonPushed(app, event)
            app.CheesecakeCheckBox.Visible="off";   
            app.FruitsCheckBox.Visible="off";        
            app.IcecreamCheckBox.Visible="off";     
            app.DountsCheckBox.Visible="off";        
            app.SpaghettiCheckBox.Visible="off";     
            app.VegetablesCheckBox.Visible="off";    
            app.SeafoodCheckBox.Visible="off";       
            app.BurgerfriesCheckBox.Visible="off";   
            app.DessertLabel.Visible="off";          
            app.MainMealLabel.Visible="off"; 
            app.OrderButton.Visible="off";           
            app.TextArea.Visible="off";  
            app.Image9.Visible="off";                
            app.Image8.Visible="off";                
            app.Image7.Visible="off";                       
            app.Image6.Visible="off";               
            app.Image5.Visible="off";                
            app.Image4.Visible="off";                
            app.Image2.Visible="off";                
            app.Image.Visible="off";   
                   
            app.BonappetitLabel.Visible="on";      
            app.Image_2.Visible="on";  
            app.Image10.Visible="on";  
            app.TextArea_2.Visible="on"; 
            app.TextArea_2.Value="Space heroes (like you) should be healty. The spaceship doesn't contain wide resources of food; so clever cookers on earth prepare some dry tasty meals and pack them for the trip, but the food is limited, so the astronauts take some pills containing all essential nutrition components."
            app.GotitButton.Visible="on";
            app.Image11.Visible="on";
        end

        % Button pushed function: GotitButton
        function GotitButtonPushed(app, event)
            app2
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'burger.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'hand-drawn-fried-fish-1_269774-218.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'flying-doughnut-melted-cartoon-icon-illustration_138676-2452.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'ice-cream-cone-three-layers-with-wafer-stick-cartoon-vector_484148-127.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'vegetable-fruits-salad-bowl-cartoon-world-food-day-concept-flat_123553-427.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'vegetable-salad-with-egg-boiled-bowl-cartoon_138676-2548.jpg';
            app.OrderButton = uibutton(app.UIFigure, 'push');
            app.OrderButton.ButtonPushedFcn = createCallbackFcn(app, @OrderButtonPushed, true);
            app.OrderButton.Text = 'Order';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'spaghetti-cartoon-illustration_138676-1485.jpg';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'vector-illustration-cake-with-strawberries-it-flat-design-style-sweet-food_587427-62.jpg';
            app.MainMealLabel = uilabel(app.UIFigure);
            app.MainMealLabel.Text = 'Main Meal';
            app.DessertLabel = uilabel(app.UIFigure);
            app.DessertLabel.Text = 'Dessert';
            app.BurgerfriesCheckBox = uicheckbox(app.UIFigure);
            app.BurgerfriesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BurgerfriesCheckBoxValueChanged, true);
            app.BurgerfriesCheckBox.Text = 'Burger & fries';
            app.SeafoodCheckBox = uicheckbox(app.UIFigure);
            app.SeafoodCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeafoodCheckBoxValueChanged, true);
            app.SeafoodCheckBox.Text = 'Seafood';
            app.VegetablesCheckBox = uicheckbox(app.UIFigure);
            app.VegetablesCheckBox.ValueChangedFcn = createCallbackFcn(app, @VegetablesCheckBoxValueChanged, true);
            app.VegetablesCheckBox.Text = 'Vegetables';
            app.SpaghettiCheckBox = uicheckbox(app.UIFigure);
            app.SpaghettiCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpaghettiCheckBoxValueChanged, true);
            app.SpaghettiCheckBox.Text = 'Spaghetti';
            app.DountsCheckBox = uicheckbox(app.UIFigure);
            app.DountsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DountsCheckBoxValueChanged, true);
            app.DountsCheckBox.Text = 'Dounts';
            app.IcecreamCheckBox = uicheckbox(app.UIFigure);
            app.IcecreamCheckBox.ValueChangedFcn = createCallbackFcn(app, @IcecreamCheckBoxValueChanged, true);
            app.IcecreamCheckBox.Text = 'Icecream';
            app.FruitsCheckBox = uicheckbox(app.UIFigure);
            app.FruitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FruitsCheckBoxValueChanged, true);
            app.FruitsCheckBox.Text = 'Fruits';
            app.CheesecakeCheckBox = uicheckbox(app.UIFigure);
            app.CheesecakeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheesecakeCheckBoxValueChanged, true);
            app.CheesecakeCheckBox.Text = 'Cheesecake';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'disssh.png';
            app.BonappetitLabel = uilabel(app.UIFigure);
            app.BonappetitLabel.Text = 'Bonappetit';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageSource = 'pill1.png';
            app.GotitButton = uibutton(app.UIFigure, 'push');
            app.GotitButton.ButtonPushedFcn = createCallbackFcn(app, @GotitButtonPushed, true);
            app.GotitButton.Text = 'Got it';
            app.Image11 = uiimage(app.UIFigure);
            app.Image11.ImageSource = 'WhiteVersion.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_hungry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end